classdef GUI_Test1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        NextJump              matlab.ui.control.Button
        UIAxes                matlab.ui.control.UIAxes
        UIAxes2               matlab.ui.control.UIAxes
        LoadVidButton         matlab.ui.control.Button
        PlayButton            matlab.ui.control.Button
        TimeSliderLabel       matlab.ui.control.Label
        TimeSlider            matlab.ui.control.Slider
        HeadStartButton       matlab.ui.control.Button
        JumpStartButton       matlab.ui.control.Button
        HeadEndButton         matlab.ui.control.Button
        JumpEndButton         matlab.ui.control.Button
        BackButton            matlab.ui.control.Button
        ForwardButton         matlab.ui.control.Button
        Frame0Label           matlab.ui.control.Label
        PrevJumpButton        matlab.ui.control.Button
        AnimalDropDownLabel   matlab.ui.control.Label
        AnimalDropDown        matlab.ui.control.DropDown
        TrialDropDownLabel    matlab.ui.control.Label
        TrialDropDown         matlab.ui.control.DropDown
        JumpNumDropDownLabel  matlab.ui.control.Label
        JumpNumDropDown       matlab.ui.control.DropDown
    end

    
    properties (Access = private)
        videoObject % Description
        CT
        xData
        yData
        Points
        Frame
        minI
        maxI
        FrameRate
        Points2
        JumpTime
    end
    
    properties (Access = public)
        HeadStart
        HeadEnd
        JumpStart
        JumpEnd
        Video % Description
        Animal
        Trial
        Date
        Jump
        Datapathname
        Trialdata
        Plot1
    end
    
    methods (Access = private)
        %         function getMousePosition(app,source,event)
        %             switch event.EventName
        %                 case 'Hit'
        %                     % Properties: Btn/IntersectionPoint/Src/Evnt
        %                     posData = event.IntersectionPoint; % to be used for calc
        %                 case 'MouseData'
        %                     % Properties: Src/EvntName
        %             end
        %         end
        %
        %         function im_ButtonDownFcn(im, hit, app)
        %             mouse_pos = flip(hit.IntersectionPoint(1:2));
        %         end
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Animal = 1;
            app.Trial = 1;
            app.Jump = 1;
            [ video_file_name,video_file_path ] = uigetfile();      %;*.png;*.yuv;*.bmp;*.tif'},'Pick a file');
            if(video_file_path == 0)
                return;
            end
            run(fullfile(video_file_path,video_file_name))
            app.Datapathname = datapathname;
            app.Trialdata = trialdata;
            app.Date = app.Trialdata(app.Animal).expt(app.Trial).date;
            app.HeadStart = zeros(length(app.Trialdata(app.Animal).expt(app.Trial).jumptime{1,1}),1);
            app.HeadEnd = zeros(length(app.Trialdata(app.Animal).expt(app.Trial).jumptime{1,1}),1);
            app.JumpStart = zeros(length(app.Trialdata(app.Animal).expt(app.Trial).jumptime{1,1}),1);
            app.JumpEnd = zeros(length(app.Trialdata(app.Animal).expt(app.Trial).jumptime{1,1}),1);
            app.AnimalDropDown.Items = {app.Trialdata.name};
            tmp=cell(length(app.Trialdata(app.Animal).expt),1);
            for i =1: length(app.Trialdata(app.Animal).expt); tmp{i,1} = num2str(i);end
            app.TrialDropDown.Items = tmp';
            tmp2=cell(length(app.Trialdata(app.Animal).expt(app.Trial).jumptime{1,1}),1);
            for i =1: length(app.Trialdata(app.Animal).expt(app.Trial).jumptime{1,1}); tmp2{i,1} = num2str(i);end
            app.JumpNumDropDown.Items = tmp2';
            
            %             app.JumpNumDropDown.Items = mat2cell(num2str([1:length(app.Trialdata(app.Animal).expt(app.Trial).jumptime{1,1})])',ones(1,length(app.Trialdata(app.Animal).expt(app.Trial).jumptime{1,1})))';
        end

        % Button pushed function: NextJump
        function NextJumpPushed(app, event)
            app.Jump = app.Jump + 1;
            if app.Jump > length(app.Trialdata(app.Animal).expt(app.Trial).jumptime{1,1})
                HeadStart = app.HeadStart; HeadEnd = app.HeadEnd; JumpStart = app.JumpStart; JumpEnd = app.JumpEnd;
                save([app.Trialdata(app.Animal).expt(app.Trial).date '_' app.Trialdata(app.Animal).name '_JumpTime'],'HeadStart','HeadEnd','JumpStart','JumpEnd')
                app.Trial = app.Trial +1;
                app.Jump = 1;
                app.HeadStart = zeros(length(app.Trialdata(app.Animal).expt(app.Trial).jumptime{1,1}),1);
                app.HeadEnd = zeros(length(app.Trialdata(app.Animal).expt(app.Trial).jumptime{1,1}),1);
                app.JumpStart = zeros(length(app.Trialdata(app.Animal).expt(app.Trial).jumptime{1,1}),1);
                app.JumpEnd = zeros(length(app.Trialdata(app.Animal).expt(app.Trial).jumptime{1,1}),1);
                if app.Trial > size(app.Trialdata(app.Animal).expt,1)
                    app.Animal = app.Animal + 1;
                end
                if app.Animal > size(app.Trialdata,1)
                    return;
                end
            end
            LoadVidButtonPushed(app,event);
            
        end

        % Button pushed function: LoadVidButton
        function LoadVidButtonPushed(app, event)
            
            %             input_video_file = 'C:\Users\corve\Desktop\DLC_Vid\012319_G6H13p4TT.mov';
            % input_video_file = fullfile(app.datapathname,app.trialdata(app.Animal).name);
            input_video_file = fullfile(app.Datapathname,[app.Trialdata(app.Animal).expt(app.Trial).date '_' app.Trialdata(app.Animal).name '.mov']);
            % Acquiring video
            app.videoObject = VideoReader(input_video_file);
            Jumptime = app.Trialdata(app.Animal).expt(app.Trial).jumptime{1,1}(app.Jump);
            
            if Jumptime < 5
                app.videoObject.CurrentTime = 0;
            else
                app.videoObject.CurrentTime = Jumptime-5;
            end
            k = 1;
            while app.videoObject.CurrentTime <= Jumptime + 5
                app.Video(:,:,k) = rgb2gray(readFrame(app.videoObject));
                k = k+1;
            end
            app.TimeSlider.Limits = [1,size(app.Video,3)];
            app.UIAxes2.Title.String = sprintf([app.Trialdata(app.Animal).name,' ' ,app.Date, ' Jump: %d'],app.Jump);
            app.FrameRate = app.videoObject.FrameRate;
            app.videoObject = VideoReader(input_video_file);
            app.JumpTime = round((Jumptime-5)*app.FrameRate);
            
            % Display first frame
            app.minI = min(app.Video(:)); app.maxI = max(app.Video(:));
            imshow(app.Video(:,:,1),[app.minI app.maxI],'Parent',app.UIAxes2);
            %             ih = imshow(app.Video(:,:,1),[app.minI app.maxI],'Parent',app.UIAxes2);
            %             ih.ButtonDownFcn = {@im_ButtonDownFcn, app};
            drawnow;
            app.Frame = 1;
            
            temp2 = dir([app.Datapathname '*' app.Trialdata(app.Animal).expt(app.Trial).date '_' app.Trialdata(app.Animal).name '*.csv']);
            Pointsxy = csvread(fullfile(temp2.folder,temp2.name),3,0);
            Pointsx = Pointsxy(:,[2:3:end]); Pointsy = Pointsxy(:,[3:3:end]);
            app.xData = Pointsx((Jumptime-5)*app.FrameRate:(Jumptime+5)*app.FrameRate,1);
            app.yData = Pointsy((Jumptime-5)*app.FrameRate:(Jumptime+5)*app.FrameRate,1);
            plot(app.UIAxes,1:length(app.xData),app.xData,1:length(app.yData),app.yData);
            legend(app.UIAxes,'X Data','Y Data');
            hold(app.UIAxes,'on')
            app.Points = scatter(app.UIAxes,[1 1],[app.xData(1,1),app.yData(1,1)],200,'.g');
            hold(app.UIAxes,'off')
            hold(app.UIAxes2,'on')
            app.Points2 = scatter(app.UIAxes2,app.xData(1,1),app.yData(1,1),200,'.r');
            hold(app.UIAxes,'off')
            
            drawnow;
        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
            isTextStart = strcmp(app.PlayButton.Text,'Play');
            isTextCont  = strcmp(app.PlayButton.Text,'Continue');
            if isTextStart
                % Two cases: (1) starting first time, or (2) restarting
                % Start from first frame
                if ~hasFrame(app.videoObject)
                    reset(app.videoObject);
                end
            end
            if (isTextStart || isTextCont)
                app.PlayButton.Text = 'Pause';
            else
                app.PlayButton.Text = 'Continue';
            end
            k=1;
            while strcmp(app.PlayButton.Text, 'Pause') && hasFrame(app.videoObject)
                %                 frame = getAndProcessFrame(app);
                imshow(app.Video(:,:,k),[app.minI app.maxI],'Parent',app.UIAxes2);
                frmnum = round(app.videoObject.CurrentTime*app.FrameRate+1);
                app.Points.XData = [frmnum; frmnum];
                app.Points.YData = [app.xData(frmnum); app.yData(frmnum)];
                app.TimeSlider.Value = frmnum;
                hold(app.UIAxes2,'on')
                app.Points2 = scatter(app.UIAxes2,app.xData(frmnum,1),app.yData(frmnum,1),200,'.r');
                hold(app.UIAxes,'off')
                drawnow;
                k=k+1;
            end
        end

        % Value changed function: TimeSlider
        function TimeSliderValueChanged(app, event)
            frmnum = round(app.TimeSlider.Value);
            imshow(app.Video(:,:,frmnum),[app.minI app.maxI],'Parent',app.UIAxes2)
            app.Points.XData = [frmnum; frmnum];
            app.Points.YData = [app.xData(frmnum); app.yData(frmnum)];
            hold(app.UIAxes2,'on')
            app.Points2 = scatter(app.UIAxes2,app.xData(frmnum,1),app.yData(frmnum,1),200,'.r');
            hold(app.UIAxes,'off')
            drawnow;
            app.Frame = frmnum;
            app.Frame0Label.Text = sprintf('Frame = %d',frmnum);
            
        end

        % Button pushed function: BackButton
        function BackButtonPushed(app, event)
            frmnum = app.Frame-1;
            imshow(app.Video(:,:,frmnum),[app.minI app.maxI],'Parent',app.UIAxes2)
            app.Points.XData = [frmnum; frmnum];
            app.Points.YData = [app.xData(frmnum); app.yData(frmnum)];
            hold(app.UIAxes2,'on')
            app.Points2 = scatter(app.UIAxes2,app.xData(frmnum,1),app.yData(frmnum,1),200,'.r');
            hold(app.UIAxes,'off')
            app.Frame = frmnum;
            app.Frame0Label.Text = sprintf('Frame = %d',frmnum);
        end

        % Button pushed function: ForwardButton
        function ForwardButtonPushed(app, event)
            frmnum = app.Frame+1;
            imshow(app.Video(:,:,frmnum),[app.minI app.maxI],'Parent',app.UIAxes2)
            app.Points.XData = [frmnum; frmnum];
            app.Points.YData = [app.xData(frmnum); app.yData(frmnum)];
            hold(app.UIAxes2,'on')
            app.Points2 = scatter(app.UIAxes2,app.xData(frmnum,1),app.yData(frmnum,1),200,'.r');
            hold(app.UIAxes,'off')
            app.Frame = frmnum;
            app.Frame0Label.Text = sprintf('Frame = %d',frmnum);
        end

        % Button pushed function: HeadStartButton
        function HeadStartButtonPushed(app, event)
            app.HeadStart(app.Jump,1) = app.Frame + app.JumpTime;
            %             assignin('base','Headstart', app.HeadStart);
        end

        % Button pushed function: JumpStartButton
        function JumpStartButtonPushed(app, event)
            app.JumpStart(app.Jump,1) = app.Frame + app.JumpTime;
        end

        % Button pushed function: HeadEndButton
        function HeadEndButtonPushed(app, event)
            app.HeadEnd(app.Jump,1) =  app.Frame + app.JumpTime;
            
        end

        % Button pushed function: JumpEndButton
        function JumpEndButtonPushed(app, event)
            app.JumpEnd(app.Jump,1)  = app.Frame + app.JumpTime;
            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
        end

        % Button pushed function: PrevJumpButton
        function PrevJumpButtonPushed(app, event)
            app.Jump = app.Jump - 1;
            if app.Jump < 1
                return;
            end
            LoadVidButtonPushed(app,event);
        end

        % Value changed function: AnimalDropDown
        function AnimalDropDownValueChanged(app, event)
            nm = app.AnimalDropDown.Value;
            app.Animal = find(strcmp({app.Trialdata.name}, nm)==1);
            tmp=cell(length(app.Trialdata(app.Animal).expt),1);
            for i =1: length(app.Trialdata(app.Animal).expt); tmp{i,1} = num2str(i);end
            app.TrialDropDown.Items = tmp';
            app.TrialDropDown.Value = num2str(1);
        end

        % Value changed function: TrialDropDown
        function TrialDropDownValueChanged(app, event)
            app.Trial = str2num(app.TrialDropDown.Value);
            tmp2=cell(length(app.Trialdata(app.Animal).expt(app.Trial).jumptime{1,1}),1);
            for i =1: length(app.Trialdata(app.Animal).expt(app.Trial).jumptime{1,1}); tmp2{i,1} = num2str(i);end
            app.JumpNumDropDown.Items = tmp2';
            app.JumpNumDropDown.Value = num2str(1);
        end

        % Value changed function: JumpNumDropDown
        function JumpNumDropDownValueChanged(app, event)
            app.Jump = str2num(app.JumpNumDropDown.Value);
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.NextJump = uibutton(app.UIFigure, 'push');
            app.NextJump.ButtonPushedFcn = createCallbackFcn(app, @NextJumpPushed, true);
            app.NextJump.Text = 'Next Jump';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'DLC Points')
            xlabel(app.UIAxes, 'Frame Number')
            ylabel(app.UIAxes, 'Pixel Value')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            app.LoadVidButton = uibutton(app.UIFigure, 'push');
            app.LoadVidButton.ButtonPushedFcn = createCallbackFcn(app, @LoadVidButtonPushed, true);
            app.LoadVidButton.Text = 'Load Vid';
            app.PlayButton = uibutton(app.UIFigure, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Text = 'Play';
            app.TimeSliderLabel = uilabel(app.UIFigure);
            app.TimeSliderLabel.Text = 'Time';
            app.TimeSlider = uislider(app.UIFigure);
            app.TimeSlider.Limits = [1 250];
            app.TimeSlider.ValueChangedFcn = createCallbackFcn(app, @TimeSliderValueChanged, true);
            app.TimeSlider.Value = 1;
            app.HeadStartButton = uibutton(app.UIFigure, 'push');
            app.HeadStartButton.ButtonPushedFcn = createCallbackFcn(app, @HeadStartButtonPushed, true);
            app.HeadStartButton.Text = 'Head Start';
            app.JumpStartButton = uibutton(app.UIFigure, 'push');
            app.JumpStartButton.ButtonPushedFcn = createCallbackFcn(app, @JumpStartButtonPushed, true);
            app.JumpStartButton.Text = 'Jump Start';
            app.HeadEndButton = uibutton(app.UIFigure, 'push');
            app.HeadEndButton.ButtonPushedFcn = createCallbackFcn(app, @HeadEndButtonPushed, true);
            app.HeadEndButton.Text = 'Head End';
            app.JumpEndButton = uibutton(app.UIFigure, 'push');
            app.JumpEndButton.ButtonPushedFcn = createCallbackFcn(app, @JumpEndButtonPushed, true);
            app.JumpEndButton.Text = 'Jump End';
            app.BackButton = uibutton(app.UIFigure, 'push');
            app.BackButton.ButtonPushedFcn = createCallbackFcn(app, @BackButtonPushed, true);
            app.BackButton.Text = 'Back';
            app.ForwardButton = uibutton(app.UIFigure, 'push');
            app.ForwardButton.ButtonPushedFcn = createCallbackFcn(app, @ForwardButtonPushed, true);
            app.ForwardButton.Text = 'Forward';
            app.Frame0Label = uilabel(app.UIFigure);
            app.Frame0Label.Text = 'Frame = 0';
            app.PrevJumpButton = uibutton(app.UIFigure, 'push');
            app.PrevJumpButton.ButtonPushedFcn = createCallbackFcn(app, @PrevJumpButtonPushed, true);
            app.PrevJumpButton.Text = 'Prev. Jump';
            app.AnimalDropDownLabel = uilabel(app.UIFigure);
            app.AnimalDropDownLabel.Text = 'Animal';
            app.AnimalDropDown = uidropdown(app.UIFigure);
            app.AnimalDropDown.Items = {'Option 1', 'Option 2', 'Option 3'};
            app.AnimalDropDown.ValueChangedFcn = createCallbackFcn(app, @AnimalDropDownValueChanged, true);
            app.TrialDropDownLabel = uilabel(app.UIFigure);
            app.TrialDropDownLabel.Text = 'Trial';
            app.TrialDropDown = uidropdown(app.UIFigure);
            app.TrialDropDown.ValueChangedFcn = createCallbackFcn(app, @TrialDropDownValueChanged, true);
            app.JumpNumDropDownLabel = uilabel(app.UIFigure);
            app.JumpNumDropDownLabel.Text = 'Jump Num.';
            app.JumpNumDropDown = uidropdown(app.UIFigure);
            app.JumpNumDropDown.ValueChangedFcn = createCallbackFcn(app, @JumpNumDropDownValueChanged, true);
        end
    end

    methods (Access = public)

        % Construct app
        function app = GUI_Test1

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end